classdef appProyectoTemperatura < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        AnalyzeDataTab             matlab.ui.container.Tab
        UIAxesDataUser             matlab.ui.control.UIAxes
        DataPanel                  matlab.ui.container.Panel
        LoadDataButton             matlab.ui.control.Button
        MeanLabel                  matlab.ui.control.Label
        StandardDeviationLabel     matlab.ui.control.Label
        VarianceLabel              matlab.ui.control.Label
        MeanValue                  matlab.ui.control.Label
        DesvValue                  matlab.ui.control.Label
        VarValue                   matlab.ui.control.Label
        SpanLabel                  matlab.ui.control.Label
        ExactLabel                 matlab.ui.control.Label
        SpanValue                  matlab.ui.control.Label
        AccuracyValue              matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        DatosTiempoRealTab         matlab.ui.container.Tab
        SerialPortConnectionPanel  matlab.ui.container.Panel
        DatainArduinoPanel         matlab.ui.container.Panel
        RUNButton                  matlab.ui.control.Button
        LedTestSwitchLabel         matlab.ui.control.Label
        LedTestSwitch              matlab.ui.control.Switch
        PanelDataPlots             matlab.ui.container.Panel
        UIAxesTemp                 matlab.ui.control.UIAxes
        SerialPortDropDownLabel    matlab.ui.control.Label
        SerialPortDropDown         matlab.ui.control.DropDown
        ConnectButton              matlab.ui.control.Button
        DisconnectButton           matlab.ui.control.Button
        BaudrateDropDownLabel      matlab.ui.control.Label
        BaudrateDropDown           matlab.ui.control.DropDown
        Image2                     matlab.ui.control.Image
    end

    
    properties (Access = private)
        actSample = 1;
        listNotAva = {'No disponible'};
        dataAnalyze;
        defaultColor = [0.96,0.96,0.96];
        defaultFontColor = [0 0 0];
        activateColor = [113 255 113]/255;
        activateFontColor = [1 1 1];
        TimerSerialList;
        TimerUpdatePlot;
        SerialPort;
        getData = false;
        PlotTemp;
        PlotDataUser;
    end
        
    methods (Access = private)
        
        function TimerSerialPortFcn(app,~,~)
            % serialportlist ---> seriallist in Matlab 2018.
            portList = serialportlist('all');
            if ~isempty(portList)
               app.SerialPortDropDown.Items = portList;
            else
               app.SerialPortDropDown.Items = app.listNotAva(end);
            end
        end
    
        function readDataSerial(app)
            % Mssgs for comunications !
            data = double( strsplit(readline(app.SerialPort) , ',') );
            app.SerialPort.UserData = [app.SerialPort.UserData; data];
        end
        
        function changeColorObj(app, objectApp, type)
            if type
                objectApp.BackgroundColor = app.activateColor;
                objectApp.FontColor = app.activateFontColor;
            else
                objectApp.BackgroundColor = app.defaultColor;
                objectApp.FontColor = app.defaultFontColor;
            end
        end
        
        function connectToSerialPort(app)
            changeColorObj(app, app.ConnectButton, 1);
            app.ConnectButton.Enable = false;
            app.DisconnectButton.Enable = true;
            app.LedTestSwitch.Enable = true;
            app.LedTestSwitchLabel.Enable = true;
            app.RUNButton.Enable = true;
            clearpoints(app.PlotTemp);
        end
        
        function disconnectToSerialPort(app)
            changeColorObj(app, app.ConnectButton, 0);
            app.ConnectButton.Enable = true;
            app.DisconnectButton.Enable = false;
            app.LedTestSwitch.Enable = false;
            app.LedTestSwitchLabel.Enable = false;
            app.RUNButton.Enable = false;
            clearpoints(app.PlotTemp);
        end
        
        function TimerUpdatePlotFcn(app,~,~)
            if app.getData && ~isempty(app.SerialPort.UserData)
                [samples,~] = size( app.SerialPort.UserData);
                if (app.actSample < samples)
                    dir = app.actSample:samples;
                    addpoints(app.PlotTemp, dir, app.SerialPort.UserData(dir));
                    drawnow limitrate nocallbacks
                end
                app.actSample = samples;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.PlotTemp = animatedline(app.UIAxesTemp,'Color', ...
                [113 255 113]/255, 'LineWidth', 1.5);
            app.PlotDataUser = plot(app.UIAxesDataUser,0,0,'Color', ...
                [113 255 113]/255);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(app.DatosTiempoRealTab.Title, selectedTab.Title)
                app.DisconnectButton.Enable = false;
                % Timer Init.
                app.TimerSerialList = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.1 , 'BusyMode', 'queue',...
                    'TimerFcn', @app.TimerSerialPortFcn);
                % Timer Init.
                app.TimerUpdatePlot = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.1 , 'BusyMode', 'queue',...
                    'TimerFcn', @app.TimerUpdatePlotFcn);
                start(app.TimerSerialList);
            else
                disconnectToSerialPort(app);
                if strcmp(app.TimerSerialList.Running,'on')
                    stop(app.TimerSerialList);
                end
                if strcmp(app.TimerUpdatePlot.Running,'on')
                    stop(app.TimerUpdatePlot);
                end
                app.SerialPort = NaN;
                pause(0.1);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.SerialPort = NaN;
            delete(app.TimerSerialList);
            delete(app.TimerUpdatePlot);
            delete(app);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Clicked data, again
            if ~strcmp(app.SerialPortDropDown.Value, app.listNotAva(1))
                connectToSerialPort(app);
                stop(app.TimerSerialList);
                pause(0.1);
                start(app.TimerUpdatePlot);
                % serialport ---> serial in Matlab 2018.
                app.SerialPort = serialport(app.SerialPortDropDown.Value,...
                    str2double(app.BaudrateDropDown.Value));
                configureTerminator(app.SerialPort,'LF');
                configureCallback(app.SerialPort,"terminator", ...
                    @(src, ~)readDataSerial(app));
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            % Save Info !
            data = app.SerialPort.UserData;
            uisave({'data'},'datosPrueba');
            app.SerialPort = NaN;
            changeColorObj(app, app.ConnectButton, 0);
            disconnectToSerialPort(app);
            stop(app.TimerUpdatePlot);
            pause(0.1);
            start(app.TimerSerialList);
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.getData = ~app.getData;
            changeColorObj(app,app.RUNButton, app.getData);
            if app.getData
                writeline(app.SerialPort, "getData");
                app.RUNButton.Text = "STOP";
            else
                writeline(app.SerialPort, "endData");
                app.RUNButton.Text = "RUN";
            end
        end

        % Value changed function: LedTestSwitch
        function LedTestSwitchValueChanged(app, event)
             if strcmp( app.LedTestSwitch.Value, "On")
                writeline(app.SerialPort, "led1On");
            else
                writeline(app.SerialPort, "led1Off");
            end   
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', 'Pick a MATLAB code file');
            if ~isequal(filename,0) && ~isequal(pathname,0)
                format = "%.3f";
                app.dataAnalyze = load(fullfile(pathname, filename));
                span = max(app.dataAnalyze.data) - min(app.dataAnalyze.data);
                range = 45 - 35;
                app.PlotDataUser.XData = (0 : length(app.dataAnalyze.data) - 1 )*500e-3;
                app.PlotDataUser.YData = app.dataAnalyze.data;
                app.MeanValue.Text = num2str(mean(app.dataAnalyze.data), format);
                app.DesvValue.Text = num2str(std(app.dataAnalyze.data), format);
                app.VarValue.Text  = num2str(var(app.dataAnalyze.data), format);
                app.SpanValue.Text = num2str(span, format);
                app.AccuracyValue.Text  = [num2str(span/range * 100, format) '%'];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.AnalyzeDataTab = uitab(app.TabGroup);
            app.AnalyzeDataTab.Title = 'Analyze Data';
            app.UIAxesDataUser = uiaxes(app.AnalyzeDataTab);
            title(app.UIAxesDataUser, 'Test Data')
            xlabel(app.UIAxesDataUser, 'Time [s]')
            ylabel(app.UIAxesDataUser, 'T [°C]')
            app.DataPanel = uipanel(app.AnalyzeDataTab);
            app.DataPanel.Title = 'Data';
            app.LoadDataButton = uibutton(app.DataPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.MeanLabel = uilabel(app.DataPanel);
            app.MeanLabel.Text = 'Mean';
            app.StandardDeviationLabel = uilabel(app.DataPanel);
            app.StandardDeviationLabel.Text = 'Standard Deviation';
            app.VarianceLabel = uilabel(app.DataPanel);
            app.VarianceLabel.Text = 'Variance';
            app.MeanValue = uilabel(app.DataPanel);
            app.MeanValue.Text = {''; ''};
            app.DesvValue = uilabel(app.DataPanel);
            app.DesvValue.Text = {''; ''};
            app.VarValue = uilabel(app.DataPanel);
            app.VarValue.Text = {''; ''};
            app.SpanLabel = uilabel(app.DataPanel);
            app.SpanLabel.Text = 'Span';
            app.ExactLabel = uilabel(app.DataPanel);
            app.ExactLabel.Text = 'Range Accuracy';
            app.SpanValue = uilabel(app.DataPanel);
            app.SpanValue.Text = {''; ''};
            app.AccuracyValue = uilabel(app.DataPanel);
            app.AccuracyValue.Text = {''; ''};
            app.Image = uiimage(app.DataPanel);
            app.Image.ImageSource = 'logoUTS.png';
            app.DatosTiempoRealTab = uitab(app.TabGroup);
            app.DatosTiempoRealTab.Title = 'Datos Tiempo Real';
            app.SerialPortConnectionPanel = uipanel(app.DatosTiempoRealTab);
            app.SerialPortConnectionPanel.Title = 'Serial Port Connection';
            app.DatainArduinoPanel = uipanel(app.DatosTiempoRealTab);
            app.DatainArduinoPanel.Title = 'Data in Arduino';
            app.RUNButton = uibutton(app.DatainArduinoPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Text = 'RUN';
            app.LedTestSwitchLabel = uilabel(app.DatainArduinoPanel);
            app.LedTestSwitchLabel.Enable = 'off';
            app.LedTestSwitchLabel.Text = 'LedTest';
            app.LedTestSwitch = uiswitch(app.DatainArduinoPanel, 'slider');
            app.LedTestSwitch.ValueChangedFcn = createCallbackFcn(app, @LedTestSwitchValueChanged, true);
            app.LedTestSwitch.Enable = 'off';
            app.PanelDataPlots = uipanel(app.DatosTiempoRealTab);
            app.PanelDataPlots.Title = 'Graphs';
            app.UIAxesTemp = uiaxes(app.PanelDataPlots);
            title(app.UIAxesTemp, 'Temperature')
            xlabel(app.UIAxesTemp, 'Samples [n]')
            ylabel(app.UIAxesTemp, 'T [C°]')
            app.UIAxesTemp.YLim = [35 45];
            app.SerialPortDropDownLabel = uilabel(app.DatosTiempoRealTab);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.DatosTiempoRealTab);
            app.SerialPortDropDown.Items = {};
            app.SerialPortDropDown.Value = {};
            app.ConnectButton = uibutton(app.DatosTiempoRealTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.DatosTiempoRealTab, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.BaudrateDropDownLabel = uilabel(app.DatosTiempoRealTab);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.DatosTiempoRealTab);
            app.BaudrateDropDown.Items = {'300', '1200', '2400', '4800', '9600', '19200', '38400', '57600', '74880', '115200', '230400', '250000', '500000', '1000000', '2000000'};
            app.BaudrateDropDown.Value = '9600';
            app.Image2 = uiimage(app.DatosTiempoRealTab);
            app.Image2.ImageSource = 'logoUTS.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appProyectoTemperatura

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end